classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        chooseDate_DropDown           matlab.ui.control.DropDown
        chooseDate_Label              matlab.ui.control.Label
        roadSelection_DropDown        matlab.ui.control.DropDown
        roadSelection_Label           matlab.ui.control.Label
        chooseTickrate_DropDown       matlab.ui.control.DropDown
        chooseTickrate_Label          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        selectTime_Slider             matlab.ui.control.Slider
        generateVisualization_Button  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    % global variables
    properties (Access = public)
        currentRoad; % road name
        currentTime; % two value - beginning and end global time
        currentTickrate; % how many part 900 seconds gets divided into
        currentSliderChoice; % currennt selected value on the slider
    end
    
    methods (Access = public)
                
        function plotRoad(app)
            cla(app.UIAxes);
           
            % Switch based on app.currentRoad value
            switch app.currentRoad
                case 'i-80'
                    shpFile = 'emeryville.shp';
                    shpFile2 = 'emeryville-camera-coverage.shp';
                    %shpFile3 = 'signs-and-signals.shp';
                case 'lankershim'
                    shpFile = 'LA-UniversalCity.shp';
                    %shpFile2 = 'lankershim-camera-coverage.shp';
                case 'us-101'
                    shpFile = 'US-101.shp';
                    shpFile2 = 'us-101-camera-coverage.shp';
                otherwise
                    error('Invalid road selection');
            end
            
            % Load the shapefile
            shp = shaperead(shpFile);
            shp2 = shaperead(shpFile2);
            %shp3 = shaperead(shpFile3);
            
            % Extract coordinates
            lon = [shp.X];
            lat = [shp.Y];
            lon2 = [shp2.X];
            lat2 = [shp2.Y];
            %lon3 = [shp3.X];
            %lat3 = [shp3.Y];
            
            % Plot the shapefile on the app.UIAxes
            plot(app.UIAxes, lon, lat, '-b', 'LineWidth', 1);
            hold(app.UIAxes,'on');
            plot(app.UIAxes, lon2, lat2, '-b', 'LineWidth', 5,'Color','green');
            %plot(app.UIAxes, lon3, lat3, '-b', 'LineWidth', 3,'Color','yellow');
            
            % Customize the map
            grid(app.UIAxes, 'on');
            
            % Add title and labels if needed
            title(app.UIAxes, app.currentRoad);
            xlabel(app.UIAxes, 'Longitude');
            ylabel(app.UIAxes, 'Latitude');
            hold(app.UIAxes,'off');
        end


        function plotCars(app)

            baseURL = 'https://data.transportation.gov/resource/8ect-6jqj.json';
            
            locationParam = ['location=', app.currentRoad];
            
            selectedInterval = 900/app.currentTickrate;

            % disp(app.currentSliderChoice);
            % disp(selectedInterval);
            % disp(app.currentSliderChoice-selectedInterval);

            minGlobalTime = app.currentTime(1) + (app.currentSliderChoice-selectedInterval)*1000;
            maxGlobalTime = minGlobalTime + (selectedInterval*1000);
            timeWhereClause = ['$where=global_time > ', num2str(minGlobalTime), ' AND global_time < ', num2str(maxGlobalTime)];
            
            % Construct the complete URL
            fullURL = [baseURL, '?', locationParam, '&', timeWhereClause];
            
            % Use the full URL for your API request
            disp(fullURL); % Display the URL (optional)
            
            % Specify the app token if needed
            appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
            options = weboptions('KeyName', 'app_token', 'KeyValue', appToken);
            
            % Retrieve data from the URL
            data = webread(fullURL, options);

            countURL = [baseURL, '?$select=count(*)&', locationParam, '&', timeWhereClause];
            disp(countURL);
            countData = webread(countURL, options);
            recordCount = countData.count;
            
            disp(recordCount);

            % Check if data is not empty
           if ~isempty(data)
                % Extract relevant columns
                    globalX = str2double({data.global_x});
                    globalY = str2double({data.global_y});
            
                    % Check for NaN values after conversion
                    validIndices = ~isnan(globalX) & ~isnan(globalY);
                    globalX = globalX(validIndices);
                    globalY = globalY(validIndices);
                
                disp(num2str(length(globalY)));
        
                % Plot the data on app.UIAxes
                % plot(app.UIAxes, globalX, globalY, 'o');  % Change the plot type if needed
                % hold off;
            else
                disp('No data retrieved from the API.');  % Display a message if data is empty
            end

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.chooseDate_DropDown.Enable = false;
            app.chooseTickrate_DropDown.Enable = false;
            app.selectTime_Slider.Enable = false;
            app.generateVisualization_Button.Enable = false;
            %{
            % Define the URL
            link = 'https://data.transportation.gov/resource/8ect-6jqj.json?location=';
            
            url = [link,app.roadSelection_ListBox.Value];
            
            % Specify the app token if needed
            appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
            options = weboptions('KeyName', 'app_token', 'KeyValue', appToken);
            
            % Retrieve data from the URL
            data = webread(url, options);
            
            % Extract unique locations
            locations = unique({data.location});
            
            % Create a MATLAB app and populate the dropdown
            app.roadSelection_ListBox.Items = locations;
            %}
        end

        % Button pushed function: generateVisualization_Button
        function generateVisualization_ButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            tickString = app.chooseTickrate_DropDown.Value;
            tickValue = str2double(tickString);
            
            % saving values

            app.currentRoad = app.roadSelection_DropDown.Value;
            app.currentTime = app.chooseDate_DropDown.Value;
            app.currentTickrate = str2double(app.chooseTickrate_DropDown.Value);
            

            if tickValue == 1
                app.currentSliderChoice = 900;
                app.selectTime_Slider.Enable = false;
                plotRoad(app);
                plotCars(app);
            else
                app.selectTime_Slider.Enable = true;
                
                % populating slider
                app.selectTime_Slider.Limits = [900/tickValue, 900];
                
                quantizedValues = 900/tickValue:900/tickValue:900;
                quantizedValuesMajor = 900/15:900/15:900;
                app.selectTime_Slider.MinorTicks = quantizedValues;
                app.selectTime_Slider.MajorTicks = quantizedValuesMajor;
                
                
                app.selectTime_Slider.Value = quantizedValues(1);
                app.currentSliderChoice = app.selectTime_Slider.Value;

                plotRoad(app);
                plotCars(app);

            end
            
        end

        % Value changed function: selectTime_Slider
        function selectTime_SliderValueChanged(app, event)
           
           value = app.selectTime_Slider.Value;

           quantizedValues = app.selectTime_Slider.MinorTicks;
           roundedValue = round(value / (900/length(quantizedValues))) * (900/length(quantizedValues));
          
           app.selectTime_Slider.Value = roundedValue;
           app.currentSliderChoice = roundedValue;
           
           plotRoad(app);
           plotCars(app);           

        end

        % Value changed function: roadSelection_DropDown
        function roadSelection_DropDownValueChanged(app, event)
            value = app.roadSelection_DropDown.Value;
            app.chooseDate_DropDown.Enable = true;
            app.chooseTickrate_DropDown.Enable = true;
          
            switch value

                case "us-101"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["7:50 - 8:05", "8:05 - 8:20", "8:20 - 8:35"];
                    ItemsData = {
                        [1118847000000, 1118847900000],
                        [1118847900000, 1118848800000],
                        [1118848800000, 1118849700000]
                    };
                    app.chooseDate_DropDown.Items = Items;
                    app.chooseDate_DropDown.ItemsData = ItemsData;
            
                case "i-80"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["16:00 - 16:15", "17:00 - 17:15", "17:15 - 17:30"];
                    ItemsData = {
                        [1113433200000, 1113434100000],
                        [1113436800000, 1113437700000],
                        [1113437700000, 1113438600000]
                    };
                    app.chooseDate_DropDown.Items = Items;
                    app.chooseDate_DropDown.ItemsData = ItemsData;
            
                case "lankershim"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["8:30 - 8:45", "8:45 - 9:00"];
                    ItemsData = {
                        [1118935800000, 1118936700000],
                        [1118936700000, 1118937600000]
                    };
                    app.chooseDate_DropDown.Items = Items;
                    app.chooseDate_DropDown.ItemsData = ItemsData;
            
                otherwise
                    app.generateVisualization_Button.Enable = false;
                    app.chooseDate_DropDown.Enable = false;
                    app.chooseTickrate_DropDown.Enable = false;        
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Traffic Visualization')
            zlabel(app.UIAxes, 'Z')
            app.generateVisualization_Button = uibutton(app.UIFigure, 'push');
            app.generateVisualization_Button.ButtonPushedFcn = createCallbackFcn(app, @generateVisualization_ButtonPushed, true);
            app.generateVisualization_Button.Text = 'Generate Visualization';
            app.selectTime_Slider = uislider(app.UIFigure);
            app.selectTime_Slider.ValueChangedFcn = createCallbackFcn(app, @selectTime_SliderValueChanged, true);
            app.selectTime_Slider.MinorTicks = [0 1.2 2.4 3.6 4.8 6 7.2 8.4 9.6 10.8 12 13.2 14.4 15.6 16.8 18 19.2 20.4 21.6 22.8 24 25.2 26.4 27.6 28.8 30 31.2 32.4 33.6 34.8 36 37.2 38.4 39.6 40.8 42 43.2 44.4 45.6 46.8 48 49.2 50.4 51.6 52.8 54 55.2 56.4 57.6 58.8 60 61.2 62.4 63.6 64.8 66 67.2 68.4 69.6 70.8 72 73.2 74.4 75.6 76.8 78 79.2 80.4 81.6 82.8 84 85.2 86.4 87.6 88.8 90 91.2 92.4 93.6 94.8 96 97.2 98.4 100];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.chooseTickrate_Label = uilabel(app.UIFigure);
            app.chooseTickrate_Label.Text = 'Choose tickrate';
            app.chooseTickrate_DropDown = uidropdown(app.UIFigure);
            app.chooseTickrate_DropDown.Items = {'15 minutes', '60 seconds', '30 seconds', '10 seconds'};
            app.chooseTickrate_DropDown.ItemsData = {'1', '15', '30', '90'};
            app.chooseTickrate_DropDown.Value = '15';
            app.roadSelection_Label = uilabel(app.UIFigure);
            app.roadSelection_Label.Text = 'Choose road';
            app.roadSelection_DropDown = uidropdown(app.UIFigure);
            app.roadSelection_DropDown.Items = {'i-80', 'us-101', 'lankershim', '-'};
            app.roadSelection_DropDown.ValueChangedFcn = createCallbackFcn(app, @roadSelection_DropDownValueChanged, true);
            app.roadSelection_DropDown.Value = '-';
            app.chooseDate_Label = uilabel(app.UIFigure);
            app.chooseDate_Label.Text = 'Choose date';
            app.chooseDate_DropDown = uidropdown(app.UIFigure);
            app.chooseDate_DropDown.Items = {};
            app.chooseDate_DropDown.Placeholder = '-';
            app.chooseDate_DropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end